classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        PanelIdentify              matlab.ui.container.Panel
        GridLayoutIdentify         matlab.ui.container.GridLayout
        ImageIdentify              matlab.ui.control.Image
        AxesIdentify               matlab.ui.control.UIAxes
        PanelMotion                matlab.ui.container.Panel
        GridLayoutMotion           matlab.ui.container.GridLayout
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes16                   matlab.ui.control.UIAxes
        UIAxes15                   matlab.ui.control.UIAxes
        UIAxes14                   matlab.ui.control.UIAxes
        UIAxes13                   matlab.ui.control.UIAxes
        UIAxes12                   matlab.ui.control.UIAxes
        UIAxes11                   matlab.ui.control.UIAxes
        UIAxes10                   matlab.ui.control.UIAxes
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes7                    matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes1                    matlab.ui.control.UIAxes
        PanelFabric                matlab.ui.container.Panel
        GridLayoutFabric           matlab.ui.container.GridLayout
        AxesFabric                 matlab.ui.control.UIAxes
        ImageCover                 matlab.ui.control.Image
        DetailsPanelTextArea       matlab.ui.control.TextArea
        DetailsPanelTextAreaLabel  matlab.ui.control.Label
        ClearDataButton            matlab.ui.control.Button
        IdentifyDataButton         matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        InitializeButton           matlab.ui.control.Button
        TaskDropDown               matlab.ui.control.DropDown
        TaskDropDownLabel          matlab.ui.control.Label
        TitleLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppPath
        Folder
        Data
        NetFabric
        MeanFabric
        StdFabric
        NetMotion
        Counter
    end
    
    methods (Access = private)
        
        function DrawFabric(app, Data)
            app.ImageCover.Visible = "off";
            app.PanelFabric.Visible = "on";
            app.PanelMotion.Visible = "off";
            app.PanelIdentify.Visible = "off";
            
            plot(app.AxesFabric, Data, 'LineWidth',1);
            xlim(app.AxesFabric, [0 3000]);
            ylim(app.AxesFabric, [-1 6]);
            legend(app.AxesFabric, 'Channel1', 'Channel2');
            ylabel(app.AxesFabric, 'Voltage (V)');
        end
        
        function DrawMotion(app, Data)
            app.ImageCover.Visible = "off";
            app.PanelFabric.Visible = "off";
            app.PanelMotion.Visible = "on";
            app.PanelIdentify.Visible = "off";
            
            for i = 1:16
                axName = ['UIAxes' num2str(i)];
                ax = app.(axName);
                plot(ax, Data(:, i), 'LineWidth',1);
                title(ax, '');
                xlabel(ax, '');
                ylabel(ax, '');
                xlim(ax, [0 50]);
                ylim(ax, [-0.1 0.1]);
                yticklabels(ax, [-0.1 0 0.1]);
                if max(abs(Data(:, i))) == 0
                    xticks(ax, [0 50]);
                    xticklabels(ax, [0 50]);
                else
                    [~, idx] = max(abs(Data(:, i)));
                    xticks(ax, [0 idx 50]);
                    xticklabels(ax, [0 idx 50]);
                end
            end
        end

        function PredictFabric(app)
            data = app.Data - mean(app.Data);
            data_normalized = (data - app.MeanFabric) ./ app.StdFabric;
            InputData = zeros(1, 3100, 2, 1);
            InputData(1, :, :, 1) = data_normalized;
            scores = predict(app.NetFabric, InputData);

            bar(app.AxesIdentify, scores);
            classNames = ["Navigation ON", "Navigation OFF", "Standard mode activated", ...
                "Sport mode activated", "Seat forward", "Seat backward", "Open the sunroof", ...
                "Close the sunroof", "Play music", "Pause music", "Answer the call", ...
                "Reject the call", "Potential hazard detected", "Warning"];
            app.AxesIdentify.XTickLabel = classNames;
            app.AxesIdentify.XTick = 1:length(classNames);
            app.AxesIdentify.YLim = [0 1];
            app.AxesIdentify.YTick = 0:0.5:1;
            app.AxesIdentify.Title.String = "Probability Distribution";

            [~, class] = max(scores);
            ImagePath = app.AppPath + "\source\figures\Fabric" + sprintf("%02d.png", class);
            app.ImageIdentify.ImageSource = ImagePath;

            txt = "Identification complete. The result is " + classNames(class) ...
                + ". The possible probabilities for each class are: ";
            for i = 1:length(classNames)
                txt = txt + classNames(i) + sprintf("(%.2f) ", floor(scores(i)*100)/100);
            end
            app.DetailsPanelTextArea.Value = txt;
        end

        function PredictMotion(app)
            InputData = zeros(1, 54, 16, 1);
            InputData(1, :, :, 1) = app.Data;
            scores = predict(app.NetMotion, InputData);

            bar(app.AxesIdentify, scores);
            classNames = ["Screen on", "Screen off", "Volume up", "Volume down", ...
                          "Seat forward", "Seat backward", "Reading light on", ...
                          "Reading light off", "Window open", "Window close"];
            app.AxesIdentify.XTickLabel = classNames;
            app.AxesIdentify.XTick = 1:length(classNames);
            app.AxesIdentify.YLim = [0 1];
            app.AxesIdentify.YTick = 0:0.5:1;
            app.AxesIdentify.Title.String = "Probability Distribution";

            [~, class] = max(scores);
            ImagePath = app.AppPath + "\source\figures\Motion" + sprintf("%02d.png", class);
            app.ImageIdentify.ImageSource = ImagePath;

            txt = "Identification complete. The result is " + classNames(class) ...
                + ". The possible probabilities for each class are: ";
            for i = 1:length(classNames)
                txt = txt + classNames(i) + sprintf("(%.2f) ", floor(scores(i)*100)/100);
            end
            app.DetailsPanelTextArea.Value = txt;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed
                app.AppPath = fullfile(ctfroot, '\App');
            else
                app.AppPath = fileparts(mfilename('fullpath')); 
            end
            
            app.Counter = 0;
            app.Folder = app.AppPath + "\source\" + app.TaskDropDown.Value + "\";

            app.ImageCover.Visible = "on";
            app.PanelFabric.Visible = "off";
            app.PanelMotion.Visible = "off";
            app.PanelIdentify.Visible = "off";

            txt = "Welcome to Interactive interface, jointly developed by Tsinghua University " + ...
                "and the University of Chinese Academy of Sciences.";
            app.DetailsPanelTextArea.Value = txt;
            app.ImageCover.ImageSource = app.AppPath + "\source\figures\cover.png";
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.Counter = 0;

            NetFabricFile_path = app.AppPath + "\source\Driver interaction\Net.mat";
            NetFabricFile = load(NetFabricFile_path);
            app.NetFabric = NetFabricFile.NET;
            app.MeanFabric = NetFabricFile.globalMean;
            app.StdFabric = NetFabricFile.globalStd;

            NetMotionFile_path = app.AppPath + "\source\Passenger interaction\Net.mat";
            NetMotionFile = load(NetMotionFile_path);
            app.NetMotion = NetMotionFile.NET;
            
            app.ImageCover.Visible = "on";
            app.PanelFabric.Visible = "off";
            app.PanelMotion.Visible = "off";
            app.PanelIdentify.Visible = "off";
            
            txt = '';
            app.DetailsPanelTextArea.Value = txt;
            app.ImageCover.ImageSource = app.AppPath + "\source\figures\cover.png";
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file_names = dir(fullfile(app.Folder, "*.mat"));
            category = length(file_names) - 1;
            % r = ceil(rand * category);
            r = mod(app.Counter * 23, category) + 1;
            app.Counter = app.Counter + 1;
            
            MFile_path = fullfile(file_names(r).folder, file_names(r).name);
            MFile = load(MFile_path);
            app.Data = MFile.DataNoisy;
            NetFile_path = fullfile(file_names(category+1).folder, file_names(category+1).name);
            NetFile = load(NetFile_path);
            app.NetFabric = NetFile.NET;

            if app.TaskDropDown.Value == "Driver interaction"
                DrawFabric(app, MFile.Data);
            elseif app.TaskDropDown.Value == "Passenger interaction"
                DrawMotion(app, MFile.Data);
            end

            txt = "Load data successfully.";
            % txt = MFile_path;
            app.DetailsPanelTextArea.Value = txt;
        end

        % Button pushed function: IdentifyDataButton
        function IdentifyDataButtonPushed(app, event)
            app.ImageCover.Visible = "off";
            app.PanelFabric.Visible = "off";
            app.PanelMotion.Visible = "off";
            
            error = isempty(app.NetFabric) || isempty(app.NetMotion) || isempty(app.Data);
            if error
                app.PanelIdentify.Visible = "off";
                txt = "Please initialize and load data first.";
                app.DetailsPanelTextArea.Value = txt;
                return;
            end

            app.PanelIdentify.Visible = "on";
            if app.TaskDropDown.Value == "Driver interaction"
                PredictFabric(app);
            elseif app.TaskDropDown.Value == "Passenger interaction"
                PredictMotion(app);
            end
            
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.Folder = app.AppPath + "\source\" + app.TaskDropDown.Value + "\";
            txt = 'Task has been changed.';
            app.DetailsPanelTextArea.Value = txt;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.Data = [];
            app.Counter = 0;

            app.ImageCover.Visible = "off";
            app.PanelFabric.Visible = "off";
            app.PanelMotion.Visible = "off";
            app.PanelIdentify.Visible = "off";

            txt = 'All data has been cleared.';
            app.DetailsPanelTextArea.Value = txt;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TitleLabel = uilabel(app.GridLayout);
            app.TitleLabel.Text = 'Interactive interface ';
            app.TaskDropDownLabel = uilabel(app.GridLayout);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.GridLayout);
            app.TaskDropDown.Items = {'Driver interaction', 'Passenger interaction'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'Driver interaction';
            app.InitializeButton = uibutton(app.GridLayout, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.IdentifyDataButton = uibutton(app.GridLayout, 'push');
            app.IdentifyDataButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyDataButtonPushed, true);
            app.IdentifyDataButton.Text = 'Identify Data';
            app.ClearDataButton = uibutton(app.GridLayout, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.DetailsPanelTextAreaLabel = uilabel(app.GridLayout);
            app.DetailsPanelTextAreaLabel.Text = 'Details Panel';
            app.DetailsPanelTextArea = uitextarea(app.GridLayout);
            app.DetailsPanelTextArea.Editable = 'off';
            app.ImageCover = uiimage(app.GridLayout);
            app.PanelFabric = uipanel(app.GridLayout);
            app.GridLayoutFabric = uigridlayout(app.PanelFabric);
            app.AxesFabric = uiaxes(app.GridLayoutFabric);
            app.PanelMotion = uipanel(app.GridLayout);
            app.GridLayoutMotion = uigridlayout(app.PanelMotion);
            app.UIAxes1 = uiaxes(app.GridLayoutMotion);
            app.UIAxes2 = uiaxes(app.GridLayoutMotion);
            app.UIAxes3 = uiaxes(app.GridLayoutMotion);
            app.UIAxes4 = uiaxes(app.GridLayoutMotion);
            app.UIAxes6 = uiaxes(app.GridLayoutMotion);
            app.UIAxes7 = uiaxes(app.GridLayoutMotion);
            app.UIAxes8 = uiaxes(app.GridLayoutMotion);
            app.UIAxes9 = uiaxes(app.GridLayoutMotion);
            app.UIAxes10 = uiaxes(app.GridLayoutMotion);
            app.UIAxes11 = uiaxes(app.GridLayoutMotion);
            app.UIAxes12 = uiaxes(app.GridLayoutMotion);
            app.UIAxes13 = uiaxes(app.GridLayoutMotion);
            app.UIAxes14 = uiaxes(app.GridLayoutMotion);
            app.UIAxes15 = uiaxes(app.GridLayoutMotion);
            app.UIAxes16 = uiaxes(app.GridLayoutMotion);
            app.UIAxes5 = uiaxes(app.GridLayoutMotion);
            app.PanelIdentify = uipanel(app.GridLayout);
            app.GridLayoutIdentify = uigridlayout(app.PanelIdentify);
            app.AxesIdentify = uiaxes(app.GridLayoutIdentify);
            title(app.AxesIdentify, 'Title')
            app.ImageIdentify = uiimage(app.GridLayoutIdentify);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end